classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InterfazPrincipal          matlab.ui.container.TabGroup
        ConfigTab                  matlab.ui.container.Tab
        LabelBaudios               matlab.ui.control.Label
        BAUDIOSLabel               matlab.ui.control.Label
        LabelPuerto                matlab.ui.control.Label
        PUERTOLabel                matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        LampLabel                  matlab.ui.control.Label
        ConnectButton              matlab.ui.control.Button
        ConfigButton               matlab.ui.control.Button
        ModomanualTab              matlab.ui.container.Tab
        Tabsmodomanual             matlab.ui.container.TabGroup
        Segmento0Tab               matlab.ui.container.Tab
        sendV2                     matlab.ui.control.Button
        sendV3                     matlab.ui.control.Button
        sendV1                     matlab.ui.control.Button
        GaugeV3                    matlab.ui.control.Gauge
        V2Label                    matlab.ui.control.Label
        GaugeV2                    matlab.ui.control.Gauge
        V2Gauge_4Label             matlab.ui.control.Label
        GaugeV1                    matlab.ui.control.Gauge
        V1Gauge_2Label             matlab.ui.control.Label
        Segmento1Tab               matlab.ui.container.Tab
        GaugeV6                    matlab.ui.control.Gauge
        V0Gauge_3Label_2           matlab.ui.control.Label
        GaugeV5                    matlab.ui.control.Gauge
        V5GaugeLabel               matlab.ui.control.Label
        GaugeV4                    matlab.ui.control.Gauge
        V4Gauge_2Label             matlab.ui.control.Label
        sendV5                     matlab.ui.control.Button
        sendV6                     matlab.ui.control.Button
        sendV4                     matlab.ui.control.Button
        Segmento2Tab               matlab.ui.container.Tab
        GaugeV9                    matlab.ui.control.Gauge
        V0Gauge_3Label_4           matlab.ui.control.Label
        GaugeV8                    matlab.ui.control.Gauge
        V8GaugeLabel               matlab.ui.control.Label
        GaugeV7                    matlab.ui.control.Gauge
        V0Gauge_3Label_3           matlab.ui.control.Label
        sendV8                     matlab.ui.control.Button
        sendV9                     matlab.ui.control.Button
        sendV7                     matlab.ui.control.Button
        PressuretosendSlider       matlab.ui.control.Slider
        PressuretosendSliderLabel  matlab.ui.control.Label
    end

    
    %Atributos publicos
    properties (Access = public) 
        mi_arduino; % El puerto serial para acceder desde más apps
        LosBaudios = 9600; %El dato de los Baudios que actualizaremos en otra app
        ElPuerto = "COM8"; %El dato del puerto del arduino que actualizaremos en otra app
        configset = false; 
        caso = '2';
        
        %Presion que se va a enviar
        millis2send = 0;

        % Objeto robot
        %miRobot  

        millisSendedToValves;

        gaugearray;


    end   


    methods (Access = public)
    
        function updateplot(app, baudio, com) %funcion para actualizar valores desde otra app
            
            app.LosBaudios = baudio;   %almacenamos las variables como propiedades            
            app.ElPuerto = com;
            app.configset = true;
            
            
            app.ConfigButton.Enable = true;     %desbloqueamos este boton al cerrar la otra app
            app.ConnectButton.Enable = true;
        end
        
    end


    methods (Access = private)
        
        % Actualiza los visores de presiones en el modo manual
        function uptdateGauges(app)
            
            aux = app.millisSendedToValves;
            
            app.GaugeV1.Value = aux(1);
            app.GaugeV2.Value = aux(2);
            app.GaugeV3.Value = aux(3);

            app.GaugeV4.Value = aux(4);
            app.GaugeV5.Value = aux(5);
            app.GaugeV6.Value = aux(6);

            app.GaugeV7.Value = aux(7);
            app.GaugeV8.Value = aux(8);
            app.GaugeV9.Value = aux(9);

             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.millisSendedToValves = [0 0 0 0 0 0 0 0 0];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                
                %app.mi_arduino=serialport(app.ElPuerto,app.LosBaudios);
                app.mi_arduino = serialport('COM22', 9600);
                %configureTerminator(app.mi_arduino,"CR/LF", "LF");
                %configureTerminator(app.mi_arduino,"CR/LF");
                %flush(app.mi_arduino);                
               
                %app.ConfigButton.Enable = false;
                %app.ConectarBoton.Enable = false;
                %pause(2);
                app.Lamp.Color = 'green';


            catch
                VentanaAdvertir;
            end
        end

        % Button pushed function: ConfigButton
        function ConfigButtonPushed(app, event)
            
            app.ConnectButton.Enable = false;
            VentanaArduino(app);
        

        end

        % Value changed function: PressuretosendSlider
        function PressuretosendSliderValueChanged(app, event)

            app.millis2send = app.PressuretosendSlider.Value;
        end

        % Button pushed function: sendV1
        function sendV1ButtonPushed(app, event)

            writeOneValveMillis(app.mi_arduino , 0, app.millis2send);
            app.millisSendedToValves(1) = app.millis2send + app.millisSendedToValves(1);
            app.uptdateGauges();
        end

        % Button pushed function: sendV2
        function sendV2ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 1, app.millis2send);
            app.millisSendedToValves(2) = app.millis2send + app.millisSendedToValves(2);
            app.uptdateGauges();
        end

        % Button pushed function: sendV3
        function sendV3ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 2, app.millis2send);
            app.millisSendedToValves(3) = app.millis2send + app.millisSendedToValves(3);
            app.uptdateGauges();
        end

        % Button pushed function: sendV4
        function sendV4ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 3, app.millis2send);
            app.millisSendedToValves(4) = app.millis2send + app.millisSendedToValves(4);
            app.uptdateGauges();
        end

        % Button pushed function: sendV5
        function sendV5ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 4, app.millis2send);
            app.millisSendedToValves(5) = app.millis2send + app.millisSendedToValves(5);
            app.uptdateGauges();
        end

        % Button pushed function: sendV6
        function sendV6ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 5, app.millis2send);
            app.millisSendedToValves(6) = app.millis2send + app.millisSendedToValves(6);
            app.uptdateGauges();
        end

        % Button pushed function: sendV7
        function sendV7ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 6, app.millis2send);
            app.millisSendedToValves(7) = app.millis2send + app.millisSendedToValves(7);
            app.uptdateGauges();
        end

        % Button pushed function: sendV8
        function sendV8ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 7, app.millis2send);
            app.millisSendedToValves(8) = app.millis2send + app.millisSendedToValves(8);
            app.uptdateGauges();
        end

        % Button pushed function: sendV9
        function sendV9ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 8, app.millis2send);
            app.millisSendedToValves(9) = app.millis2send + app.millisSendedToValves(9);
            app.uptdateGauges();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterfazPrincipal = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.InterfazPrincipal);
            app.ConfigTab.Title = 'Config';
            app.ConfigButton = uibutton(app.ConfigTab, 'push');
            app.ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigButtonPushed, true);
            app.ConfigButton.Text = 'Config';
            app.ConnectButton = uibutton(app.ConfigTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LampLabel = uilabel(app.ConfigTab);
            app.LampLabel.Text = {''; ''};
            app.Lamp = uilamp(app.ConfigTab);
            app.PUERTOLabel = uilabel(app.ConfigTab);
            app.PUERTOLabel.Text = 'PUERTO: ';
            app.LabelPuerto = uilabel(app.ConfigTab);
            app.BAUDIOSLabel = uilabel(app.ConfigTab);
            app.BAUDIOSLabel.Text = 'BAUDIOS';
            app.LabelBaudios = uilabel(app.ConfigTab);
            app.LabelBaudios.Text = '';
            app.ModomanualTab = uitab(app.InterfazPrincipal);
            app.ModomanualTab.Title = 'Modo manual';
            app.PressuretosendSliderLabel = uilabel(app.ModomanualTab);
            app.PressuretosendSliderLabel.Text = 'Pressure to send';
            app.PressuretosendSlider = uislider(app.ModomanualTab);
            app.PressuretosendSlider.Limits = [-1000 1000];
            app.PressuretosendSlider.ValueChangedFcn = createCallbackFcn(app, @PressuretosendSliderValueChanged, true);
            app.Tabsmodomanual = uitabgroup(app.ModomanualTab);
            app.Segmento0Tab = uitab(app.Tabsmodomanual);
            app.Segmento0Tab.Title = 'Segmento 0';
            app.V1Gauge_2Label = uilabel(app.Segmento0Tab);
            app.V1Gauge_2Label.Text = 'V1';
            app.GaugeV1 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV1.Limits = [-5000 5000];
            app.V2Gauge_4Label = uilabel(app.Segmento0Tab);
            app.V2Gauge_4Label.Text = 'V2';
            app.GaugeV2 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV2.Limits = [-5000 5000];
            app.V2Label = uilabel(app.Segmento0Tab);
            app.V2Label.Text = 'V3';
            app.GaugeV3 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV3.Limits = [-5000 5000];
            app.sendV1 = uibutton(app.Segmento0Tab, 'push');
            app.sendV1.ButtonPushedFcn = createCallbackFcn(app, @sendV1ButtonPushed, true);
            app.sendV1.Text = 'send';
            app.sendV3 = uibutton(app.Segmento0Tab, 'push');
            app.sendV3.ButtonPushedFcn = createCallbackFcn(app, @sendV3ButtonPushed, true);
            app.sendV3.Text = 'send';
            app.sendV2 = uibutton(app.Segmento0Tab, 'push');
            app.sendV2.ButtonPushedFcn = createCallbackFcn(app, @sendV2ButtonPushed, true);
            app.sendV2.Text = 'send';
            app.Segmento1Tab = uitab(app.Tabsmodomanual);
            app.Segmento1Tab.Title = 'Segmento 1';
            app.sendV4 = uibutton(app.Segmento1Tab, 'push');
            app.sendV4.ButtonPushedFcn = createCallbackFcn(app, @sendV4ButtonPushed, true);
            app.sendV4.Text = 'send';
            app.sendV6 = uibutton(app.Segmento1Tab, 'push');
            app.sendV6.ButtonPushedFcn = createCallbackFcn(app, @sendV6ButtonPushed, true);
            app.sendV6.Text = 'send';
            app.sendV5 = uibutton(app.Segmento1Tab, 'push');
            app.sendV5.ButtonPushedFcn = createCallbackFcn(app, @sendV5ButtonPushed, true);
            app.sendV5.Text = 'send';
            app.V4Gauge_2Label = uilabel(app.Segmento1Tab);
            app.V4Gauge_2Label.Text = 'V4';
            app.GaugeV4 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV4.Limits = [-5000 5000];
            app.V5GaugeLabel = uilabel(app.Segmento1Tab);
            app.V5GaugeLabel.Text = 'V5';
            app.GaugeV5 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV5.Limits = [-5000 5000];
            app.V0Gauge_3Label_2 = uilabel(app.Segmento1Tab);
            app.V0Gauge_3Label_2.Text = 'V6';
            app.GaugeV6 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV6.Limits = [-5000 5000];
            app.Segmento2Tab = uitab(app.Tabsmodomanual);
            app.Segmento2Tab.Title = 'Segmento 2';
            app.sendV7 = uibutton(app.Segmento2Tab, 'push');
            app.sendV7.ButtonPushedFcn = createCallbackFcn(app, @sendV7ButtonPushed, true);
            app.sendV7.Text = 'send';
            app.sendV9 = uibutton(app.Segmento2Tab, 'push');
            app.sendV9.ButtonPushedFcn = createCallbackFcn(app, @sendV9ButtonPushed, true);
            app.sendV9.Text = 'send';
            app.sendV8 = uibutton(app.Segmento2Tab, 'push');
            app.sendV8.ButtonPushedFcn = createCallbackFcn(app, @sendV8ButtonPushed, true);
            app.sendV8.Text = 'send';
            app.V0Gauge_3Label_3 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_3.Text = 'V7';
            app.GaugeV7 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV7.Limits = [-5000 5000];
            app.V8GaugeLabel = uilabel(app.Segmento2Tab);
            app.V8GaugeLabel.Text = 'V8';
            app.GaugeV8 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV8.Limits = [-5000 5000];
            app.V0Gauge_3Label_4 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_4.Text = 'V9';
            app.GaugeV9 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV9.Limits = [-5000 5000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end